classdef V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        maeTRAINval               matlab.ui.control.Label
        maeTESTval                matlab.ui.control.Label
        LoadingLabel              matlab.ui.control.Label
        PredictingValue           matlab.ui.control.ListBox
        PredictingSelectionLabel  matlab.ui.control.Label
        MAXSPLITTINGSlider        matlab.ui.control.Slider
        MAXSPLITTINGSliderLabel   matlab.ui.control.Label
        FeatureSelection          matlab.ui.container.CheckBoxTree
        FeatureSelectionLabel     matlab.ui.control.Label
        SAVESUBMISSIONButton      matlab.ui.control.Button
        STARTButton               matlab.ui.control.Button
        TESTButton                matlab.ui.control.Button
        TRAINButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TRAIN_DATA
        TEST_DATA
        TreeRegressor
        X
        y
        testX
        testY
        features
        predictingValue
        predicted
        TRAINcolumnNames
        TESTcolumnNames
        slideNumber
        maeTEST
        maeTRAIN
        trueValuesTEST
        trueValuesTRAIN
        changingValue
    end
    
    methods (Access = private)
        
        function submissionKaggle(app, predictions, id, variableNames, path)
            submission = table(id, predictions);
            submission.Properties.VariableNames = variableNames;
            writetable(submission, path);
        end

        function mae = callMAE(app, orgSig,recSig,varargin)
            if isempty(varargin)
                boun = 0;
            else boun = varargin{1};
            end
            if size(orgSig,2)==1       % if signal is 1-D
                orgSig = orgSig(boun+1:end-boun,:);
                recSig = recSig(boun+1:end-boun,:);
            else                       % if signal is 2-D or 3-D
                orgSig = orgSig(boun+1:end-boun,boun+1:end-boun,:);
                recSig = recSig(boun+1:end-boun,boun+1:end-boun,:);
            end
            
            absErr = norm(orgSig(:)-recSig(:),1);
            mae = absErr/length(orgSig(:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TRAINButton
        function TRAINButtonPushed(app, event)
            [name, path] = uigetfile("*.csv");
            app.TRAIN_DATA = fullfile(path, name);
            if ~isequal(app.TRAIN_DATA, 0)
                set(app.TESTButton, "visible", "on");
                set(app.FeatureSelection, "visible", "on");
                set(app.PredictingValue, "visible", "on");
                set(app.FeatureSelectionLabel, "visible", "on");
                set(app.PredictingSelectionLabel, "visible", "on");
            end
            app.TRAIN_DATA = readtable(app.TRAIN_DATA);
            app.TRAINcolumnNames = app.TRAIN_DATA.Properties.VariableNames;
            for i=1:length(app.TRAINcolumnNames)
                child = uitreenode(app.FeatureSelection,"Text", app.TRAINcolumnNames{1,i});
            end
            app.PredictingValue.Items = app.TRAINcolumnNames;
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            [name, path] = uigetfile("*.csv");
            app.TEST_DATA = fullfile(path, name);
            if ~isequal(app.TEST_DATA, 0)
                set(app.SAVESUBMISSIONButton, "visible", "on");
                set(app.STARTButton, "visible", "on");
                set(app.MAXSPLITTINGSliderLabel, "visible", "on");
            end 
            app.TEST_DATA = readtable(app.TEST_DATA);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            featureTree = app.FeatureSelection.CheckedNodes;
            app.predictingValue = app.PredictingValue.Value;
            app.features = [""];
            for i=1:length(featureTree)
                feature = convertCharsToStrings(featureTree(i).Text);
                app.features(end + 1) = feature;
            end
            app.features(1) = [] ;
            app.X = app.TRAIN_DATA(:, app.features);
            app.y = app.TRAIN_DATA(:, app.predictingValue);

            app.testX = app.TEST_DATA(:, app.features);
            
            app.maeTEST = [];
            app.maeTRAIN = [];
            app.trueValuesTEST = readtable("/home/muhammed/Desktop/maxSplitSimulator/trueValues.csv");
            app.trueValuesTEST = app.trueValuesTEST(:, app.predictingValue);
            app.slideNumber = (app.MAXSPLITTINGSlider.Limits(1): app.MAXSPLITTINGSlider.Limits(2));
            app.trueValuesTEST = table2array(app.trueValuesTEST);
            set(app.LoadingLabel, "visible", "on");
            
            for i=1:length(app.slideNumber)
                app.TreeRegressor = fitctree(app.X, app.y, "MaxNumSplits", i);
                predictedCurrentTEST = app.TreeRegressor.predict(app.testX);
                predictedCurrentTRAIN = app.TreeRegressor.predict(app.X);
                maeCurrentTEST = app.callMAE(predictedCurrentTEST, app.trueValuesTEST);
                maeCurrentTRAIN = app.callMAE(predictedCurrentTRAIN, table2array(app.y));
                app.maeTEST(end + 1) = maeCurrentTEST;
                app.maeTRAIN(end + 1) = maeCurrentTRAIN;
            end
            set(app.LoadingLabel, "visible", "off");
            set(app.MAXSPLITTINGSlider, "visible", "on");
            set(app.UIAxes, "visible", "on");

            set(app.LoadingLabel, "visible", "off");
            plot(app.UIAxes, app.slideNumber, app.maeTEST);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.slideNumber, app.maeTRAIN);
            hold(app.UIAxes,'on');
            app.UIAxes.Title.String = 'ERROR OF TRAIN AND TEST';
            legend(app.UIAxes, ["maeTEST", "maeTRAIN", "valueTEST", "valueTRAIN"]);
            app.UIAxes.XLabel.String = "SPLIT NUMBER";
            app.UIAxes.YLabel.String = "ERROR";
        end

        % Button pushed function: SAVESUBMISSIONButton
        function SAVESUBMISSIONButtonPushed(app, event)
            path = "/home/muhammed/Desktop/submission.csv";
            app.predicted = fitctree(app.X, app.y, "MaxNumSplits", app.changingValue);
            app.predicted = app.predicted.predict(app.testX);
            app.submissionKaggle(app.predicted, app.TEST_DATA.Id, ["Id", app.predictingValue], path);
        end

        % Value changing function: MAXSPLITTINGSlider
        function MAXSPLITTINGSliderValueChanging(app, event)
            app.changingValue = event.Value;
            app.changingValue = round(app.changingValue);
            set(app.maeTESTval, "Text", string([app.changingValue, app.maeTEST(app.changingValue)]), "FontColor", [0 0 1]);
            set(app.maeTRAINval, "Text", string([app.changingValue, app.maeTRAIN(app.changingValue)]), "FontColor", [1 0 0]);
            
            app.UIAxes.Title.String = 'ERROR OF TRAIN AND TEST';
            legend(app.UIAxes, ["maeTEST", "maeTRAIN", "valueTEST", "valueTRAIN"]);
            app.UIAxes.XLabel.String = "SPLIT NUMBER";
            app.UIAxes.YLabel.String = "ERROR";
            plot(app.UIAxes, app.slideNumber, app.maeTEST);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.slideNumber, app.maeTRAIN);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.changingValue, app.maeTEST(app.changingValue), "s" ,"color", [1 0 0]);
            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.changingValue, app.maeTRAIN(app.changingValue), "*" ,"color", [0 0 1]);
            hold(app.UIAxes, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.TRAINButton = uibutton(app.UIFigure, 'push');
            app.TRAINButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINButtonPushed, true);
            app.TRAINButton.Text = 'TRAIN';
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Visible = 'off';
            app.TESTButton.Text = 'TEST';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            app.SAVESUBMISSIONButton = uibutton(app.UIFigure, 'push');
            app.SAVESUBMISSIONButton.ButtonPushedFcn = createCallbackFcn(app, @SAVESUBMISSIONButtonPushed, true);
            app.SAVESUBMISSIONButton.Visible = 'off';
            app.SAVESUBMISSIONButton.Text = 'SAVE SUBMISSION';
            app.FeatureSelectionLabel = uilabel(app.UIFigure);
            app.FeatureSelectionLabel.Visible = 'off';
            app.FeatureSelectionLabel.Text = 'Feature Selection';
            app.FeatureSelection = uitree(app.UIFigure, 'checkbox');
            app.FeatureSelection.Visible = 'off';
            app.MAXSPLITTINGSliderLabel = uilabel(app.UIFigure);
            app.MAXSPLITTINGSliderLabel.Visible = 'off';
            app.MAXSPLITTINGSliderLabel.Text = 'MAX SPLITTING';
            app.MAXSPLITTINGSlider = uislider(app.UIFigure);
            app.MAXSPLITTINGSlider.Limits = [5 500];
            app.MAXSPLITTINGSlider.ValueChangingFcn = createCallbackFcn(app, @MAXSPLITTINGSliderValueChanging, true);
            app.MAXSPLITTINGSlider.Visible = 'off';
            app.MAXSPLITTINGSlider.Value = 5;
            app.PredictingSelectionLabel = uilabel(app.UIFigure);
            app.PredictingSelectionLabel.Visible = 'off';
            app.PredictingSelectionLabel.Text = 'Predicting Selection';
            app.PredictingValue = uilistbox(app.UIFigure);
            app.PredictingValue.Items = {};
            app.PredictingValue.Visible = 'off';
            app.PredictingValue.Value = {};
            app.LoadingLabel = uilabel(app.UIFigure);
            app.LoadingLabel.Visible = 'off';
            app.LoadingLabel.Text = 'Loading...';
            app.maeTESTval = uilabel(app.UIFigure);
            app.maeTESTval.Text = '';
            app.maeTRAINval = uilabel(app.UIFigure);
            app.maeTRAINval.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end